classdef SpikeMan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig1                      matlab.ui.Figure
        LoadROIPanel              matlab.ui.container.Panel
        loadexp                   matlab.ui.control.Button
        ExppathEditFieldLabel     matlab.ui.control.Label
        exppath                   matlab.ui.control.EditField
        ROISpinnerLabel           matlab.ui.control.Label
        currROI                   matlab.ui.control.Spinner
        WhiskvarLabel             matlab.ui.control.Label
        whisk                     matlab.ui.control.EditField
        loadwhisk                 matlab.ui.control.Button
        roizscore                 matlab.ui.control.Button
        keeproi                   matlab.ui.control.Button
        delroi                    matlab.ui.control.Button
        sigmasnr                  matlab.ui.control.Button
        dtsecEditFieldLabel       matlab.ui.control.Label
        dt                        matlab.ui.control.NumericEditField
        loaddt                    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        SpikeInferencePanel       matlab.ui.container.Panel
        MethodListBoxLabel        matlab.ui.control.Label
        method                    matlab.ui.control.ListBox
        SigmaSpinnerLabel         matlab.ui.control.Label
        sigma                     matlab.ui.control.Spinner
        aSpinnerLabel             matlab.ui.control.Label
        a                         matlab.ui.control.Spinner
        loadpar                   matlab.ui.control.Button
        bkpar                     matlab.ui.control.Button
        finalise                  matlab.ui.control.Button
        LoadparfromLabel          matlab.ui.control.Label
        BookmarkDropDownLabel     matlab.ui.control.Label
        loadfrombk                matlab.ui.control.DropDown
        spkinf                    matlab.ui.control.Button
        orLabel                   matlab.ui.control.Label
        saveall                   matlab.ui.control.Button
        ModelDropDownLabel        matlab.ui.control.Label
        model                     matlab.ui.control.DropDown
        tonEditFieldLabel         matlab.ui.control.Label
        ton                       matlab.ui.control.NumericEditField
        hillEditFieldLabel        matlab.ui.control.Label
        hill                      matlab.ui.control.NumericEditField
        tauEditFieldLabel         matlab.ui.control.Label
        tau                       matlab.ui.control.NumericEditField
        SaturationEditFieldLabel  matlab.ui.control.Label
        sat                       matlab.ui.control.NumericEditField
        PlottingPanel             matlab.ui.container.Panel
        clfig                     matlab.ui.control.Button
        savefig                   matlab.ui.control.Button
        whichplot                 matlab.ui.container.ButtonGroup
        plotb                     matlab.ui.control.RadioButton
        plotr                     matlab.ui.control.RadioButton
        addspkroi                 matlab.ui.control.NumericEditField
        addspk                    matlab.ui.control.Button
        tminEditFieldLabel        matlab.ui.control.Label
        tmin                      matlab.ui.control.NumericEditField
        tmaxEditFieldLabel        matlab.ui.control.Label
        tmax                      matlab.ui.control.NumericEditField
        tmaxS                     matlab.ui.control.Slider
        tminS                     matlab.ui.control.Slider
    end


    properties (Access = private)
       spike_buffer 
       
       blueline
       redline
    end

    properties (Access = public)
        par % Description
        roi_params
        plot_params
        exp_params
        spk_manual
        rawF
        curr_spkres
    end

    methods (Access = private)
    
        function update_par( app, par )
            app.sigma.Value = par.finetune.sigma;
            app.method.Value = par.algo.estimate;
            app.a.Value = par.a;
            app.tau.Value = par.tau;
            app.ton.Value = par.ton;
            app.hill.Value = par.hill;
            app.sat.Value = par.saturation;
            
        end
        
    end

    methods (Access = public)
    
        function update_rawF(app)
            assignin( 'base', 'roi', app.currROI.Value );
            evalin( 'base', 'update_raw;');
            app.rawF = evalin( 'base', 'rawF' );
        end
        
        function update_plot( app, F, spikes )
            ax = app.UIAxes;
            hold(ax, 'off');
            linecol = app.plot_params.color;
            plot(app.UIAxes, app.dt.Value*[1:length(F)]*1e3,smooth(F,3)+0.1, linecol);
            hold(ax, 'on');
            switch linecol
                case 'r'
                    app.redline = spikes;
                case 'b'
                    app.blueline = spikes;
            end
            stem(app.UIAxes, app.blueline*1e3, -0.15*ones(size(app.blueline)), 'b','Marker','none', 'linewidth',1)
            stem(app.UIAxes, app.redline*1e3, 0.15*ones(size(app.redline)), 'r','Marker','none', 'linewidth',1)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if isempty(app.exppath.Value) 
                if evalin( 'base', 'exist(''exp'',''var'') == 1;' ) 
                    exp =  evalin( 'base', 'exp');
                    app.exppath.Value = exp.analysed;  
                end
                if evalin( 'base', 'exist(''beh_all'',''var'') == 1;' ) 
                    evalin( 'base', 'whisk = beh_all.whisk;');
                    app.whisk.Value = 'whisk';  
                end
            end
            if evalin( 'base', 'exist(''p'',''var'') == 1;' ) 
                p = evalin('base','p');
                app.exp_params = p;
                app.dt.Value = 1/p.acquisition_rate; 
            end
               
            switch app.whichplot.SelectedObject.Text
                case 'Lower'
                    app.plot_params.color = 'b';
                    app.plot_params.spkht = -1;
                case 'Upper'
                    app.plot_params.color = 'r';
                    app.plot_params.spkht = 1;
            end
       
            app.par = tps_mlspikes('par');
            app.par.dt = app.dt.Value;
            app.par.a = app.a.Value;
            app.par.tau = app.tau.Value;
            app.par.ton = app.ton.Value;
            app.par.saturation = app.sat.Value;
            app.par.finetune.sigma = app.sigma.Value;
            app.par.drift.parameter = .015; 
            app.par.F0 = [0,1];
            app.par.algo.estimate = app.method.Value;
        end

        % Value changed function: exppath
        function exppathValueChanged(app, event)
            app.exp_params.exppath = app.exppath.Value;
        end

        % Button pushed function: roizscore
        function roizscoreButtonPushed(app, event)
            allF = evalin('base', 'zscore(activity_all.soma)');
            app.exp_params.maxT = app.dt.Value*length(allF)*1e3;
            app.tmax.Value = app.exp_params.maxT * app.tmaxS.Value;
            pid = prctile( allF, 97, 1 );
            app.roi_params.keep_roi = (pid>1.9);
            app.roi_params.gid = find(app.roi_params.keep_roi);
            if isempty( app.spk_manual ), app.spk_manual = struct('spikes',[],'par',[], 'roi', [], 'drift', [], 'F', [], 'fit', []);
            end
        end

        % Button pushed function: loaddt
        function loaddtButtonPushed(app, event)
            app.dt.Value = evalin('base', 'dt');
        end

        % Button pushed function: keeproi
        function keeproiButtonPushed(app, event)
            if ~isfield( app.roi_params, 'keep_roi')
                app.roi_params.keep_roi = false( app.exp_params.nROIs, 1); 
                
            end 
            app.roi_params.keep_roi(app.currROI.Value) = true;
            app.roi_params.gid = find(app.roi_params.keep_roi);
        end

        % Button pushed function: delroi
        function delroiButtonPushed(app, event)
            if ~isfield( app.roi_params, 'keep_roi'), app.roi_params.keep_roi = false( app.exp_params.nROIs, 1); end 
            app.roi_params.keep_roi(app.currROI.Value) = false;
            app.roi_params.gid = find(app.roi_params.keep_roi);
        end

        % Button pushed function: sigmasnr
        function sigmasnrButtonPushed(app, event)
            evalin('base','nonactive_for_snr');
            app.roi_params.snr = evalin('base','snr');
        end

        % Value changed function: sigma
        function sigmaValueChanged(app, event)
            app.par.finetune.sigma = app.sigma.Value;
            
        end

        % Value changed function: currROI
        function currROIValueChanged(app, event)
            value = app.currROI.Value;
            app.spike_buffer = [];
            app.redline = [];
            app.blueline = [];
            
            hold(app.UIAxes, 'off');
            try
                app.curr_spkres = app.spk_manual(value);
                if ~isempty( app.curr_spkres.F)
                    app.update_par( app.curr_spkres.par );
                    app.rawF = app.curr_spkres.F;
                else
                    app.curr_spkres = struct('spikes',[],'par',[], 'roi', value, 'drift', [], 'F', [], 'fit', []);
                    app.update_rawF;
                    app.curr_spkres.F = app.rawF;
                end
            catch
                app.curr_spkres = struct('spikes',[],'par',[], 'roi', value, 'drift', [], 'F', [], 'fit', []);
                app.update_rawF;
                app.curr_spkres.F = app.rawF;
            end
            if ~isfield( app.exp_params, 'maxT')
                app.exp_params.maxT = app.dt.Value*length(app.rawF)*1e3; 
                app.tmax.Value = app.exp_params.maxT * app.tmaxS.Value;
            end
            app.update_plot( app.curr_spkres.F, app.curr_spkres.spikes );
        end

        % Button pushed function: loadexp
        function loadexpButtonPushed(app, event)
            if isempty( app.exppath.Value )
                app.exppath.Value = uigetdir( pwd, 'Choose analysis directory:');
                app.exp_params.exppath = app.exppath.Value;
            end
            assignin('base', 'exppath',app.exppath.Value);
            evalin('base', "load( [exppath, '\exp_details.mat'], 'exp')");
            evalin('base', "load( [exppath, '\masked_norm_test.mat'])");
            evalin('base', "load( [exppath, '\masked_raw.mat'])");
            evalin('base', "load( [exppath, '\conactenated.mat'])");
            evalin('base', 'load_raw;');
        end

        % Button pushed function: loadpar
        function loadparButtonPushed(app, event)
            app.par = evalin('base', 'par' );
            update_par( app, app.par );
        end

        % Selection changed function: whichplot
        function whichplotSelectionChanged(app, event)
            switch app.whichplot.SelectedObject.Text
                case 'Lower'
                    app.plot_params.color = 'b';
                    app.plot_params.spkht = -1;
                case 'Upper'
                    app.plot_params.color = 'r';
                    app.plot_params.spkht = 1;
            end
            
        end

        % Value changed function: tmin
        function tminValueChanged(app, event)
            xlim(app.UIAxes,[app.tmin.Value, app.tmax.Value]);
        end

        % Value changed function: tmax
        function tmaxValueChanged(app, event)
            xlim(app.UIAxes,[app.tmin.Value, app.tmax.Value]);
        end

        % Value changed function: tminS
        function tminSValueChanged(app, event)
            app.tmin.Value = app.tminS.Value*app.exp_params.maxT;
            xlim(app.UIAxes,[app.tmin.Value, app.tmax.Value]);
        end

        % Value changed function: tmaxS
        function tmaxSValueChanged(app, event)
            app.tmax.Value = app.tmaxS.Value*app.exp_params.maxT;
            xlim(app.UIAxes,[app.tmin.Value, app.tmax.Value]);
        end

        % Button pushed function: spkinf
        function spkinfButtonPushed(app, event)
            if isempty( app.spk_manual ), app.spk_manual = struct('spikes',[],'par',[], 'roi', [], 'drift', [], 'F', [], 'fit', []);
            end
            [spikest, fit, drift] = spk_est(app.rawF,app.par);
            app.curr_spkres =  struct('par', app.par, 'spikes', spikest, 'F', app.rawF, 'roi', app.currROI.Value, 'fit', fit, 'drift', drift);
            app.update_plot( app.rawF, spikest );
        end

        % Button pushed function: bkpar
        function bkparButtonPushed(app, event)
            n = length(app.spike_buffer);
            app.spike_buffer(n+1) = app.curr_spkres;
        end

        % Value changed function: a
        function aValueChanged(app, event)
            app.par.a = app.a.Value;
            
        end

        % Value changed function: ton
        function tonValueChanged(app, event)
            app.par.ton = app.ton.Value;
            
        end

        % Value changed function: tau
        function tauValueChanged(app, event)
            app.par.tau = app.tau.Value;
            
        end

        % Value changed function: hill
        function hillValueChanged(app, event)
            app.par.hill = app.hill.Value;
            
        end

        % Value changed function: sat
        function satValueChanged(app, event)
            app.par.saturation = app.sat.Value;
            
        end

        % Button pushed function: finalise
        function finaliseButtonPushed(app, event)
            if isempty( app.spk_manual ), app.spk_manual = struct('spikes',[],'par',[], 'roi', [], 'drift', [], 'F', [], 'fit', []);
            end
            app.spk_manual( app.currROI.Value ) = app.curr_spkres;
        end

        % Button pushed function: saveall
        function saveallButtonPushed(app, event)
            assignin('base', 'spk_manual', app.spk_manual( app.roi_params.gid) )
            evalin( 'base', "save( [exppath, '\spikes_manualcurated_app.mat'], 'spk_manual', '-v7.3')" )
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig1 = uifigure;
            app.fig1.Name = 'SpikeMan(ual)';
            app.LoadROIPanel = uipanel(app.fig1);
            app.LoadROIPanel.Title = 'Load ROI';
            app.loadexp = uibutton(app.LoadROIPanel, 'push');
            app.loadexp.ButtonPushedFcn = createCallbackFcn(app, @loadexpButtonPushed, true);
            app.loadexp.Text = 'Load';
            app.ExppathEditFieldLabel = uilabel(app.LoadROIPanel);
            app.ExppathEditFieldLabel.Text = 'Exp path';
            app.exppath = uieditfield(app.LoadROIPanel, 'text');
            app.exppath.ValueChangedFcn = createCallbackFcn(app, @exppathValueChanged, true);
            app.ROISpinnerLabel = uilabel(app.LoadROIPanel);
            app.ROISpinnerLabel.Text = 'ROI';
            app.currROI = uispinner(app.LoadROIPanel);
            app.currROI.Limits = [1 10];
            app.currROI.RoundFractionalValues = 'on';
            app.currROI.ValueChangedFcn = createCallbackFcn(app, @currROIValueChanged, true);
            app.currROI.Value = 1;
            app.WhiskvarLabel = uilabel(app.LoadROIPanel);
            app.WhiskvarLabel.Text = 'Whisk var';
            app.whisk = uieditfield(app.LoadROIPanel, 'text');
            app.loadwhisk = uibutton(app.LoadROIPanel, 'push');
            app.loadwhisk.Text = 'Load';
            app.roizscore = uibutton(app.LoadROIPanel, 'push');
            app.roizscore.ButtonPushedFcn = createCallbackFcn(app, @roizscoreButtonPushed, true);
            app.roizscore.Text = 'Select ROI by Zscore';
            app.keeproi = uibutton(app.LoadROIPanel, 'push');
            app.keeproi.ButtonPushedFcn = createCallbackFcn(app, @keeproiButtonPushed, true);
            app.keeproi.Text = 'Keep ROI';
            app.delroi = uibutton(app.LoadROIPanel, 'push');
            app.delroi.ButtonPushedFcn = createCallbackFcn(app, @delroiButtonPushed, true);
            app.delroi.Text = 'Remove ROI';
            app.sigmasnr = uibutton(app.LoadROIPanel, 'push');
            app.sigmasnr.ButtonPushedFcn = createCallbackFcn(app, @sigmasnrButtonPushed, true);
            app.sigmasnr.Text = 'Set Sigma by SNR';
            app.dtsecEditFieldLabel = uilabel(app.LoadROIPanel);
            app.dtsecEditFieldLabel.Text = 'dt (sec)';
            app.dt = uieditfield(app.LoadROIPanel, 'numeric');
            app.dt.Limits = [0 Inf];
            app.dt.Value = 0.02;
            app.loaddt = uibutton(app.LoadROIPanel, 'push');
            app.loaddt.ButtonPushedFcn = createCallbackFcn(app, @loaddtButtonPushed, true);
            app.loaddt.Text = 'Load';
            app.UIAxes = uiaxes(app.fig1);
            title(app.UIAxes, 'Inferred spikes')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Activity')
            app.SpikeInferencePanel = uipanel(app.fig1);
            app.SpikeInferencePanel.Title = 'Spike Inference';
            app.MethodListBoxLabel = uilabel(app.SpikeInferencePanel);
            app.MethodListBoxLabel.Text = 'Method';
            app.method = uilistbox(app.SpikeInferencePanel);
            app.method.Items = {'MAP', 'Spike prob'};
            app.method.Value = 'MAP';
            app.SigmaSpinnerLabel = uilabel(app.SpikeInferencePanel);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.sigma = uispinner(app.SpikeInferencePanel);
            app.sigma.Step = 0.001;
            app.sigma.Limits = [0 Inf];
            app.sigma.ValueDisplayFormat = '%.4f';
            app.sigma.ValueChangedFcn = createCallbackFcn(app, @sigmaValueChanged, true);
            app.aSpinnerLabel = uilabel(app.SpikeInferencePanel);
            app.aSpinnerLabel.Text = 'a';
            app.a = uispinner(app.SpikeInferencePanel);
            app.a.Step = 0.01;
            app.a.Limits = [0 1];
            app.a.ValueChangedFcn = createCallbackFcn(app, @aValueChanged, true);
            app.a.Value = 0.15;
            app.loadpar = uibutton(app.SpikeInferencePanel, 'push');
            app.loadpar.ButtonPushedFcn = createCallbackFcn(app, @loadparButtonPushed, true);
            app.loadpar.Text = 'Workspace';
            app.bkpar = uibutton(app.SpikeInferencePanel, 'push');
            app.bkpar.ButtonPushedFcn = createCallbackFcn(app, @bkparButtonPushed, true);
            app.bkpar.Text = 'Bookmark';
            app.finalise = uibutton(app.SpikeInferencePanel, 'push');
            app.finalise.ButtonPushedFcn = createCallbackFcn(app, @finaliseButtonPushed, true);
            app.finalise.Text = 'Finalise';
            app.LoadparfromLabel = uilabel(app.SpikeInferencePanel);
            app.LoadparfromLabel.Text = 'Load par from:';
            app.BookmarkDropDownLabel = uilabel(app.SpikeInferencePanel);
            app.BookmarkDropDownLabel.Text = 'Bookmark';
            app.loadfrombk = uidropdown(app.SpikeInferencePanel);
            app.loadfrombk.Items = {'1'};
            app.loadfrombk.Editable = 'on';
            app.loadfrombk.Value = '1';
            app.spkinf = uibutton(app.SpikeInferencePanel, 'push');
            app.spkinf.ButtonPushedFcn = createCallbackFcn(app, @spkinfButtonPushed, true);
            app.spkinf.Text = 'Infer spikes';
            app.orLabel = uilabel(app.SpikeInferencePanel);
            app.orLabel.Text = 'or';
            app.saveall = uibutton(app.SpikeInferencePanel, 'push');
            app.saveall.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveall.Text = 'Save all';
            app.ModelDropDownLabel = uilabel(app.SpikeInferencePanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.model = uidropdown(app.SpikeInferencePanel);
            app.model.Items = {'GCaMP6f', 'OGB', 'Custom'};
            app.model.Editable = 'on';
            app.model.Value = 'GCaMP6f';
            app.tonEditFieldLabel = uilabel(app.SpikeInferencePanel);
            app.tonEditFieldLabel.Text = 'ton';
            app.ton = uieditfield(app.SpikeInferencePanel, 'numeric');
            app.ton.Limits = [-20 100];
            app.ton.ValueChangedFcn = createCallbackFcn(app, @tonValueChanged, true);
            app.hillEditFieldLabel = uilabel(app.SpikeInferencePanel);
            app.hillEditFieldLabel.Text = 'hill';
            app.hill = uieditfield(app.SpikeInferencePanel, 'numeric');
            app.hill.Limits = [0 20];
            app.hill.ValueChangedFcn = createCallbackFcn(app, @hillValueChanged, true);
            app.hill.Value = 1.7;
            app.tauEditFieldLabel = uilabel(app.SpikeInferencePanel);
            app.tauEditFieldLabel.Text = 'tau';
            app.tau = uieditfield(app.SpikeInferencePanel, 'numeric');
            app.tau.Limits = [0 10];
            app.tau.ValueChangedFcn = createCallbackFcn(app, @tauValueChanged, true);
            app.tau.Value = 0.76;
            app.SaturationEditFieldLabel = uilabel(app.SpikeInferencePanel);
            app.SaturationEditFieldLabel.Text = 'Saturation';
            app.sat = uieditfield(app.SpikeInferencePanel, 'numeric');
            app.sat.Limits = [0 1];
            app.sat.ValueChangedFcn = createCallbackFcn(app, @satValueChanged, true);
            app.sat.Value = 0.1;
            app.PlottingPanel = uipanel(app.fig1);
            app.PlottingPanel.Title = 'Plotting';
            app.clfig = uibutton(app.PlottingPanel, 'push');
            app.clfig.Text = 'Clear Figure';
            app.savefig = uibutton(app.PlottingPanel, 'push');
            app.savefig.Text = 'Save Figure';
            app.whichplot = uibuttongroup(app.PlottingPanel);
            app.whichplot.SelectionChangedFcn = createCallbackFcn(app, @whichplotSelectionChanged, true);
            app.whichplot.Title = 'Currently plotting spikes on:';
            app.plotb = uiradiobutton(app.whichplot);
            app.plotb.Text = 'Lower';
            app.plotb.Value = true;
            app.plotr = uiradiobutton(app.whichplot);
            app.plotr.Text = 'Upper';
            app.addspkroi = uieditfield(app.PlottingPanel, 'numeric');
            app.addspkroi.Limits = [1 Inf];
            app.addspkroi.Value = 1;
            app.addspk = uibutton(app.PlottingPanel, 'push');
            app.addspk.Text = 'Add ROI';
            app.tminEditFieldLabel = uilabel(app.PlottingPanel);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tmin = uieditfield(app.PlottingPanel, 'numeric');
            app.tmin.ValueChangedFcn = createCallbackFcn(app, @tminValueChanged, true);
            app.tmaxEditFieldLabel = uilabel(app.PlottingPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmax = uieditfield(app.PlottingPanel, 'numeric');
            app.tmax.ValueChangedFcn = createCallbackFcn(app, @tmaxValueChanged, true);
            app.tmax.Value = 100000;
            app.tmaxS = uislider(app.PlottingPanel);
            app.tmaxS.Limits = [0 1];
            app.tmaxS.MajorTickLabels = {'0', '', '', '', '', '1'};
            app.tmaxS.ValueChangedFcn = createCallbackFcn(app, @tmaxSValueChanged, true);
            app.tmaxS.Value = 1;
            app.tminS = uislider(app.PlottingPanel);
            app.tminS.Limits = [0 1];
            app.tminS.MajorTickLabels = {'0', '', '', '', '', '1'};
            app.tminS.ValueChangedFcn = createCallbackFcn(app, @tminSValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpikeMan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fig1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig1)
        end
    end
end